classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        MetatedMenu        matlab.ui.container.Menu
        TedsMenu           matlab.ui.container.Menu
        Canal1Menu         matlab.ui.container.Menu
        Canal2Menu         matlab.ui.container.Menu
        Canal3Menu         matlab.ui.container.Menu
        Canal4Menu         matlab.ui.container.Menu
        Canal5Menu         matlab.ui.container.Menu
        Canal6Menu         matlab.ui.container.Menu
        LercanalMenu       matlab.ui.container.Menu
        Canal1Menu_2       matlab.ui.container.Menu
        Canal2Menu_2       matlab.ui.container.Menu
        Canal3Menu_2       matlab.ui.container.Menu
        EscrevercanalMenu  matlab.ui.container.Menu
        C                  matlab.ui.control.NumericEditField
        VEditFieldLabel    matlab.ui.control.Label
        V                  matlab.ui.control.NumericEditField
        CEditFieldLabel    matlab.ui.control.Label
        EnviarButton       matlab.ui.control.StateButton
        inst               matlab.ui.control.EditField
        disp               matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: MetatedMenu
        function MetatedMenuSelected(app, event)
            stim = serialport("/dev/ttyACM0", 9600);
            % Ask metateds
            write(stim, [0 0 1 2 0 2 1 0], "uint8");
            suc = read(stim,3, "uint8");
            if (suc(1) ==0)
                pp.disp.Value ="Erro nas metateds"
                flag=0;
            else
                teds = read(stim,suc(3), "uint8");

                app.disp.Value = num2str(teds)

                display(teds);
                flag=1;
            end
            clc; clear;

        end

        % Menu selected function: Canal1Menu
        function Canal1MenuSelected(app, event)
            stim = serialport("/dev/ttyACM0", 9600);
            % Ask channel 1 info
            write(stim, [0 1 1 2 0 2 3 0], "uint8");
            suc = read(stim,3, "uint8");
            if (suc(1) ==0)
                pp.disp.Value ="Erro channel 1 info"
                flag=0;
            else
                teds = read(stim,suc(3), "uint8");
                app.disp.Value = num2str(teds)
                flag=1;
            end
            clc; clear;
        end

        % Menu selected function: Canal2Menu
        function Canal2MenuSelected(app, event)
             stim = serialport("/dev/ttyACM0", 9600);
            % Ask channel 1 info
            write(stim, [0 2 1 2 0 2 3 0], "uint8");
            suc = read(stim,3, "uint8");
            if (suc(1) ==0)
                pp.disp.Value ="Erro channel 2 info"
                flag=0;
            else
                teds = read(stim,suc(3), "uint8");
                app.disp.Value = num2str(teds)
                flag=1;
            end
            clc; clear;
        end

        % Menu selected function: Canal3Menu
        function Canal3MenuSelected(app, event)
             stim = serialport("/dev/ttyACM0", 9600);
            % Ask channel 1 info
            write(stim, [0 3 1 2 0 2 3 0], "uint8");
            suc = read(stim,3, "uint8");
            if (suc(1) ==0)
                pp.disp.Value ="Erro channel 3 info"
                flag=0;
            else
                teds = read(stim,suc(3), "uint8");
                app.disp.Value = num2str(teds)
                flag=1;
            end
            clc; clear;
        end

        % Menu selected function: Canal4Menu
        function Canal4MenuSelected(app, event)
             stim = serialport("/dev/ttyACM0", 9600);
            % Ask channel 1 info
            write(stim, [0 4 1 2 0 2 3 0], "uint8");
            suc = read(stim,3, "uint8");
            if (suc(1) ==0)
                pp.disp.Value ="Erro channel 4 info"
                flag=0;
            else
                teds = read(stim,suc(3), "uint8");
                app.disp.Value = num2str(teds)
                flag=1;
            end
            clc; clear;
        end

        % Menu selected function: Canal5Menu
        function Canal5MenuSelected(app, event)
              stim = serialport("/dev/ttyACM0", 9600);
            % Ask channel 1 info
            write(stim, [0 5 1 2 0 2 3 0], "uint8");
            suc = read(stim,3, "uint8");
            if (suc(1) ==0)
                pp.disp.Value ="Erro channel 5 info"
                flag=0;
            else
                teds = read(stim,suc(3), "uint8");
                app.disp.Value = num2str(teds)
                flag=1;
            end
            clc; clear;
        end

        % Menu selected function: Canal6Menu
        function Canal6MenuSelected(app, event)
              stim = serialport("/dev/ttyACM0", 9600);
            % Ask channel 1 info
            write(stim, [0 6 1 2 0 2 3 0], "uint8");
            suc = read(stim,3, "uint8");
            if (suc(1) ==0)
                pp.disp.Value ="Erro channel 5 info"
                flag=0;
            else
                teds = read(stim,suc(3), "uint8");
                app.disp.Value = num2str(teds)
                flag=1;
            end
            clc; clear;
        end

        % Menu selected function: Canal1Menu_2
        function Canal1Menu_2Selected(app, event)
            stim = serialport("/dev/ttyACM0", 9600);
            write(stim, [0 1 3 1 0 1 0], "uint8");
            suc = read(stim,3, "uint8")
            if (suc(1) ==0)
                pp.disp.Value ="Erro ao ler channel 5"
                flag=0;
            else
                teds = read(stim,suc(3), "uint8");
                display(teds);
                flag=1;
            end
            clc; clear;
        end

        % Menu selected function: Canal2Menu_2
        function Canal2Menu_2Selected(app, event)
            stim = serialport("/dev/ttyACM0", 9600);
            write(stim, [0 2 3 1 0 1 0], "uint8");
            suc = read(stim,3, "uint8")
            if (suc(1) ==0)
                pp.disp.Value ="Erro ao ler channel 5"
                flag=0;
            else
                teds = read(stim,suc(3), "uint8");
                display(teds);
                flag=1;
            end
            clc; clear;

        end

        % Menu selected function: Canal3Menu_2
        function Canal3Menu_2Selected(app, event)
            stim = serialport("/dev/ttyACM0", 9600);
            write(stim, [0 3 3 1 0 1 0], "uint8");
            suc = read(stim,3, "uint8")
            if (suc(1) ==0)
                pp.disp.Value ="Erro ao ler channel 5"
                flag=0;
            else
                teds = read(stim,suc(3), "uint8");write(stim,Tvw, "uint8");
                display(teds);
                flag=1;
            end

            clc; clear;


        end

        % Menu selected function: EscrevercanalMenu
        function EscrevercanalMenuSelected(app, event)
            app.inst.Visible=1;
            app.EnviarButton.Visible=1;
            app.disp.Visible=0;
            app.C.Visible =1;
            app.V.Visible =1;
        end

        % Value changed function: EnviarButton
        function EnviarButtonValueChanged(app, event)
            stim = serialport("/dev/ttyACM0", 9600);
            c = app.C.Value;
            v = app.V.Value;
            m = [0 c 3 2 0 2 0 v];
            display(m)
            write(stim,m, "uint8");
            clc; clear;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MetatedMenu = uimenu(app.UIFigure);
            app.MetatedMenu.MenuSelectedFcn = createCallbackFcn(app, @MetatedMenuSelected, true);
            app.MetatedMenu.Text = 'Metated';
            app.TedsMenu = uimenu(app.UIFigure);
            app.TedsMenu.Text = 'Teds';
            app.Canal1Menu = uimenu(app.TedsMenu);
            app.Canal1Menu.MenuSelectedFcn = createCallbackFcn(app, @Canal1MenuSelected, true);
            app.Canal1Menu.Text = 'Canal 1';
            app.Canal2Menu = uimenu(app.TedsMenu);
            app.Canal2Menu.MenuSelectedFcn = createCallbackFcn(app, @Canal2MenuSelected, true);
            app.Canal2Menu.Text = 'Canal 2';
            app.Canal3Menu = uimenu(app.TedsMenu);
            app.Canal3Menu.MenuSelectedFcn = createCallbackFcn(app, @Canal3MenuSelected, true);
            app.Canal3Menu.Text = 'Canal 3';
            app.Canal4Menu = uimenu(app.TedsMenu);
            app.Canal4Menu.MenuSelectedFcn = createCallbackFcn(app, @Canal4MenuSelected, true);
            app.Canal4Menu.Text = 'Canal 4';
            app.Canal5Menu = uimenu(app.TedsMenu);
            app.Canal5Menu.MenuSelectedFcn = createCallbackFcn(app, @Canal5MenuSelected, true);
            app.Canal5Menu.Text = 'Canal 5';
            app.Canal6Menu = uimenu(app.TedsMenu);
            app.Canal6Menu.MenuSelectedFcn = createCallbackFcn(app, @Canal6MenuSelected, true);
            app.Canal6Menu.Text = 'Canal 6';
            app.LercanalMenu = uimenu(app.UIFigure);
            app.LercanalMenu.Text = 'Ler canal';
            app.Canal1Menu_2 = uimenu(app.LercanalMenu);
            app.Canal1Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Canal1Menu_2Selected, true);
            app.Canal1Menu_2.Text = 'Canal 1';
            app.Canal2Menu_2 = uimenu(app.LercanalMenu);
            app.Canal2Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Canal2Menu_2Selected, true);
            app.Canal2Menu_2.Text = 'Canal 2';
            app.Canal3Menu_2 = uimenu(app.LercanalMenu);
            app.Canal3Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Canal3Menu_2Selected, true);
            app.Canal3Menu_2.Text = 'Canal 3';
            app.EscrevercanalMenu = uimenu(app.UIFigure);
            app.EscrevercanalMenu.MenuSelectedFcn = createCallbackFcn(app, @EscrevercanalMenuSelected, true);
            app.EscrevercanalMenu.Text = 'Escrever canal';
            app.disp = uieditfield(app.UIFigure, 'text');
            app.inst = uieditfield(app.UIFigure, 'text');
            app.inst.Editable = 'off';
            app.inst.Visible = 'off';
            app.inst.Value = 'Escreva na caixa abaixo 0 c 3 2 0 2 0 v, onde c pode ser 4,5 ou 6 e corresponde a cada um dos canais dos leds e v corresponde ao valor que pretende escrever.';
            app.EnviarButton = uibutton(app.UIFigure, 'state');
            app.EnviarButton.ValueChangedFcn = createCallbackFcn(app, @EnviarButtonValueChanged, true);
            app.EnviarButton.Visible = 'off';
            app.EnviarButton.Text = 'Enviar';
            app.CEditFieldLabel = uilabel(app.UIFigure);
            app.CEditFieldLabel.Text = 'C';
            app.V = uieditfield(app.UIFigure, 'numeric');
            app.VEditFieldLabel = uilabel(app.UIFigure);
            app.VEditFieldLabel.Text = 'V';
            app.C = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end